classdef IndicesCorrelaciones < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        Button              matlab.ui.control.Button
        Button_2            matlab.ui.control.Button
        UITable             matlab.ui.control.Table
        UITable2            matlab.ui.control.Table
        IndicesLabel        matlab.ui.control.Label
        CorrelacionesLabel  matlab.ui.control.Label
        ExportarButton      matlab.ui.control.Button
        AgregarButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        Indices 
        IndicesPromediados
        Reflectancias
        ReflectanciasProm
        Tratamientos
        idBD
        idsTratamientos
        listaCorrelacion
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, indicesFinales, reflectancias, tratamientos, idBD, indicesPromediados, reflectanciasProm)
            app.UIFigure.WindowState = 'maximized';

            app.Indices = indicesFinales;
            app.Reflectancias = reflectancias;
            app.Tratamientos = tratamientos;
            app.idBD = idBD;
            app.IndicesPromediados = indicesPromediados;
            app.ReflectanciasProm = reflectanciasProm;
            
            etiquetas = strings(length(app.Tratamientos)+1);
            
            keySet = keys(indicesPromediados{1});
            tdata = [];
            keySet = keySet.'; 
            tdata = [tdata keySet];
            
            for i = 1:length(app.IndicesPromediados)
                valueSet = values(indicesPromediados{i});
                valueSet = valueSet.';
                tdata = [tdata valueSet];
            end
            
            etiquetas(1) = 'Indices';
            for i = 2:length(app.Tratamientos)+1
                etiquetas(i) = app.Tratamientos(i-1);
            end
            
            app.UITable.Data = tdata;
            set(app.UITable, 'ColumnName' , etiquetas);
            
        end

        % Button pushed function: ExportarButton
        function ExportarButtonPushed(app, event)
            cd 'C:\HYPER'
            if(app.idBD ~= 0)
                query = sprintf('SELECT nombre FROM andes WHERE id = %d LIMIT 1', app.idBD);
                nombreBD = databaseConnectionSelect(query);
                nombreBD = table2cell(nombreBD);
            else
                nombreBD = cellstr('Local');
            end

            mkdir ([nombreBD{1}])
            cd ([nombreBD{1}])
            
            fecha = datetime('now');
            fecha.Format = 'yyyy-MM-dd HH:mm:ss';
            fecha = datestr(fecha);
            fecha = replace(fecha,":",".");
            mkdir ([fecha])
            cd([fecha])
            
            mkdir INDICES
            %cd (['C:\HYPER\', nombreBD{1}, '\INDICES'])
            cd 'C:\HYPER'
            
            startName = ['C:\HYPER\', nombreBD{1}, '\', fecha , '\INDICES\'];
            endNameIndice = '-INDICES.csv';
            endNameReflectancia = '-REFLECTANCIA.csv';
            endNameCorrelacion = '-CORRELACION.csv';
            app.idsTratamientos = zeros(length(app.Tratamientos),1);
            d = uiprogressdlg(app.UIFigure,'Title','Por favor espere', 'Message','Exportando archivos');
            pause(.5);
            
            d.Value = .33;
            d.Message = 'Cargando Archivos';
            pause(1);
            
            cont = 0.4;

            %Exportación local. Indices promediados
            %
            for i = 1:length(app.Tratamientos)
                cd ([startName])
                tratamientoLocal = app.Tratamientos(i);
                mkdir (tratamientoLocal);
                cd 'C:\HYPER'
                localCorrelacion = [];
                data2= get(app.UITable2, 'Data');
                corr = data2;
            
                %concatenar
                localFileIndice = strcat(startName, app.Tratamientos(i), '\', app.Tratamientos(i), endNameIndice);
                localFileReflectancia = strcat(startName, app.Tratamientos(i), '\', app.Tratamientos(i), endNameReflectancia);
                disp(localFileIndice)
                %exportar                
                exportarIndicesNuevo(localFileIndice{1}, app.IndicesPromediados{i});
                exportarReflectanciaNuevo(localFileReflectancia{1}, app.ReflectanciasProm(i,:));
                
                
                %encontrarCorrelaciones
                localFileCorrelacion = strcat(startName, app.Tratamientos(i), '\', app.Tratamientos(i), endNameCorrelacion);
                for j = 1:size(corr)
                    correlacion = cell2table(corr(j,:),'VariableNames',{'Tratamiento','Nombre','Valor'});
                    correlacion = table2struct(correlacion);
                    if(strcmp(correlacion.Tratamiento, app.Tratamientos(i)))
                        localCorrelacion = [localCorrelacion correlacion];
                    end
                end
                if(~isempty(localCorrelacion))
                    exportarCorrelaciones(localFileCorrelacion, localCorrelacion);                    
                end
                
            end
            
            rep = 1;
            %Exportación local. Indices por repetición
            for i = 1:length(app.Tratamientos)
                cd ([startName])
                tratamientoLocal = app.Tratamientos(i);
                mkdir (tratamientoLocal);
                cd 'C:\HYPER'
                
                repsTratamiento = app.Indices(i);
                repsTratamiento = repsTratamiento{1};
                %localRep = 1;
                disp('lengthT');
                disp(size(repsTratamiento,2));
                for k=1:size(repsTratamiento,2)
                    %concatenar
                    strRep = sprintf('%d', k);
                    localFileIndice = strcat(startName, tratamientoLocal, '\', tratamientoLocal, 'R', strRep, endNameIndice);
                    localFileReflectancia = strcat(startName, tratamientoLocal, '\', tratamientoLocal, 'R', strRep, endNameReflectancia);
                    disp(localFileIndice)
                    disp('repsT');
                    disp(repsTratamiento);
                    disp(repsTratamiento{k});
                    
                    %exportar                
                    exportarIndicesNuevo(localFileIndice{1}, repsTratamiento{k});
                    exportarReflectanciaNuevo(localFileReflectancia{1}, app.Reflectancias(rep,:));
                    
                    rep = rep + 1;
                    %localRep = localRep + 1;
                end                
            end
            
            
            %conexión BD. Guarda los promedios de los tratamientos.
            if(app.idBD ~= 0)
                for i = 1:length(app.Tratamientos)
                    tratamientoLocal = app.Tratamientos(i);
                    id = agregarTratamientoReflectancia(app.idBD,tratamientoLocal,app.ReflectanciasProm(i,:));
                    app.idsTratamientos(i) = id;
                    
                    agregarInstanciaIndice(app.IndicesPromediados{i}, id, app.idBD);   
                    
                    d.Value = cont;
                    d.Message = 'Cargando a la Base de datos';
                    pause(1);
                    cont = cont + 0.1;
                    
                end
                for i = 1:length(app.listaCorrelacion)
                   % st = app.listaCorrelacion(i);
                    for j = 1:length(app.Tratamientos)
                        localTrat = app.Tratamientos(j); 
                        if(strcmp(app.listaCorrelacion(i).Tratamiento,localTrat) )
                            insertarCorrelacion(app.listaCorrelacion(i).Nombre,app.listaCorrelacion(i).Valor,app.idsTratamientos(j))    
                        end                        
                    end
                end
            end
            
            %concatenar
            %fileNameIndice = strcat(startName, file, endNameIndice);
            %fileNameReflectancia = strcat(startName, file, endNameReflectancia);
            d.Value = 1;
            d.Message = 'Finalizando';
            pause(1);
            close(d);
            %exportar
            %exportarIndicesNuevo(fileNameIndice{1}, app.Indices);
            %exportarReflectanciaNuevo(fileNameReflectancia{1}, app.Reflectancia);
            
            h = msgbox('Archivos generados satisfactoriamente', 'Enhorabuena');
            posn = get(h, 'Position');
            set(h, 'Position', posn+[0 0 20 0])
        end

        % Button pushed function: AgregarButton
        function AgregarButtonPushed(app, event)
            
            %etiquetas = cell(length(app.Tratamientos));
            %for i = 1:length(app.Tratamientos)
             %   etiquetas = [etiquetas ; cellstr(app.Tratamientos(i))];
            %end
            disp(class(app.Tratamientos))
            trat = cellstr(app.Tratamientos);
            disp(class(trat))
            disp(trat)
            
            [Answer,Cancelled,app.listaCorrelacion] = modalcorrelacion(trat);
            
            tdata = get(app.UITable2,'Data');
            for i = 1:length(app.listaCorrelacion)
                data = struct2cell(app.listaCorrelacion(i));
                data = data.';
                tdata = [tdata ; data];
            end
            
            set(app.UITable2, 'ColumnName', {'Tratamiento','Correlación','Valor'});
            set(app.UITable2, 'Data', tdata);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            answer = questdlg('Al volver atrás va a perder los índices agregados. ¿Desea continuar?', 'Volver atrás', 'Sí', 'No gracias', 'No');
            % Handle response
            switch answer
                case 'Sí'
                    IndicesNxM(app.Reflectancias, app.Tratamientos, app.idBD);
                    app.delete
                case 'No gracias'
                    %Se mantiene en la vista
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app2();
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'backButton.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'homeButton.png';
            app.Button_2.Text = '';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Column'};
            app.UITable.RowName = {};
            app.UITable2 = uitable(app.GridLayout);
            app.UITable2.ColumnName = {'Column'};
            app.UITable2.RowName = {};
            app.IndicesLabel = uilabel(app.GridLayout);
            app.IndicesLabel.Text = 'Indices';
            app.CorrelacionesLabel = uilabel(app.GridLayout);
            app.CorrelacionesLabel.Text = 'Correlaciones';
            app.ExportarButton = uibutton(app.GridLayout, 'push');
            app.ExportarButton.ButtonPushedFcn = createCallbackFcn(app, @ExportarButtonPushed, true);
            app.ExportarButton.Text = 'Exportar';
            app.AgregarButton = uibutton(app.GridLayout, 'push');
            app.AgregarButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarButtonPushed, true);
            app.AgregarButton.Text = 'Agregar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IndicesCorrelaciones(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end