classdef ModalBD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        TabGroup                  matlab.ui.container.TabGroup
        CargarBasedeDatosTab      matlab.ui.container.Tab
        GridLayout2               matlab.ui.container.GridLayout
        BasedeDatosDropDownLabel  matlab.ui.control.Label
        BasedeDatosDropDown       matlab.ui.control.DropDown
        AceptarButton             matlab.ui.control.Button
        CrearBasedeDatosTab       matlab.ui.container.Tab
        GridLayout3               matlab.ui.container.GridLayout
        NombreEditFieldLabel      matlab.ui.control.Label
        NombreEditField           matlab.ui.control.EditField
        AceptarButton_2           matlab.ui.control.Button
        LocalTab                  matlab.ui.container.Tab
        GridLayout4               matlab.ui.container.GridLayout
    end

    
    methods (Access = private)
        
        function llenarDropDown(app)
            conn = database('MySQL','root','');
            
            query = sprintf('SELECT nombre FROM andes;');
            disp(query);
            data = table2cell(fetch(conn,query));
            disp(length(data));
            disp(class(data));
            disp(data);
            
            app.BasedeDatosDropDown.Items = data;
            
            close(conn);
            clear conn query
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Resize = 'off';
            llenarDropDown(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.CargarBasedeDatosTab = uitab(app.TabGroup);
            app.CargarBasedeDatosTab.Title = 'Cargar Base de Datos';
            app.GridLayout2 = uigridlayout(app.CargarBasedeDatosTab);
            app.BasedeDatosDropDownLabel = uilabel(app.GridLayout2);
            app.BasedeDatosDropDownLabel.Text = 'Base de Datos';
            app.BasedeDatosDropDown = uidropdown(app.GridLayout2);
            app.AceptarButton = uibutton(app.GridLayout2, 'push');
            app.AceptarButton.Text = 'Aceptar';
            app.CrearBasedeDatosTab = uitab(app.TabGroup);
            app.CrearBasedeDatosTab.Title = 'Crear Base de Datos';
            app.GridLayout3 = uigridlayout(app.CrearBasedeDatosTab);
            app.NombreEditFieldLabel = uilabel(app.GridLayout3);
            app.NombreEditFieldLabel.Text = 'Nombre ';
            app.NombreEditField = uieditfield(app.GridLayout3, 'text');
            app.AceptarButton_2 = uibutton(app.GridLayout3, 'push');
            app.AceptarButton_2.Text = 'Aceptar';
            app.LocalTab = uitab(app.TabGroup);
            app.LocalTab.Title = 'Local';
            app.GridLayout4 = uigridlayout(app.LocalTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModalBD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end