classdef calcularIndices < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        LeftPanel       matlab.ui.container.Panel
        GridLayout2     matlab.ui.container.GridLayout
        Button          matlab.ui.control.Button
        Button_2        matlab.ui.control.Button
        RightPanel      matlab.ui.container.Panel
        GridLayout3     matlab.ui.container.GridLayout
        UITable         matlab.ui.control.Table
        ExportarButton  matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Indices 
        Reflectancia
        FileName
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, indicesFinales, reflectancia, fileName)
            app.UIFigure.WindowState = 'maximized';

            app.Indices = indicesFinales;
            app.Reflectancia = reflectancia;
            app.FileName = fileName;
            
            keySet = keys(indicesFinales);
            valueSet = values(indicesFinales);
            
            keySet = keySet.';
            valueSet = valueSet.';
            
            tdata = [keySet valueSet];
                        
            app.UITable.Data = tdata;
            
            name = split(app.FileName, '_');            
            file = name(1);
            
            set(app.UITable, 'ColumnName', {'Índices', file{1}});

            
        end

        % Button pushed function: ExportarButton
        function ExportarButtonPushed(app, event)
            %guardar en metodo (Ti, PUNTUAL, etc)
            
            cd 'C:\HYPER'
            mkdir INDICES
            cd 'C:\HYPER\INDICES'
            mkdir PUNTUAL
            cd 'C:\HYPER'
    
            startName = 'C:\HYPER\INDICES\PUNTUAL\';
            
            name = split(app.FileName, '_');            
            file = name(1);
            
            endNameIndice = '-INDICES.csv';
            endNameReflectancia = '-REFLECTANCIA.csv';
            
            %concatenar
            fileNameIndice = strcat(startName, file, endNameIndice);
            fileNameReflectancia = strcat(startName, file, endNameReflectancia);
            
            %exportar
            exportarIndicesNuevo(fileNameIndice{1}, app.Indices);
            exportarReflectanciaNuevo(fileNameReflectancia{1}, app.Reflectancia);
            
            h = msgbox('Archivos generados satisfactoriamente', 'Enhorabuena');
            posn = get(h, 'Position');
            set(h, 'Position', posn+[0 0 20 0])
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app2();
            app.delete;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            answer = questdlg('Al volver atrás va a perder los índices agregados. ¿Desea continuar?', 'Volver atrás', 'Sí', 'No gracias', 'No');
            % Handle response
            switch answer
                case 'Sí'
                    app1(app.Reflectancia, app.FileName);
                    app.delete;
                case 'No gracias'
                    %Se mantiene en la vista
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.Button = uibutton(app.GridLayout2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'backButton.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.GridLayout2, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'homeButton.png';
            app.Button_2.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.UITable = uitable(app.GridLayout3);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable.RowName = {};
            app.ExportarButton = uibutton(app.GridLayout3, 'push');
            app.ExportarButton.ButtonPushedFcn = createCallbackFcn(app, @ExportarButtonPushed, true);
            app.ExportarButton.Text = 'Exportar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calcularIndices(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end