classdef readFiles < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        GridLayout2         matlab.ui.container.GridLayout
        Button_3            matlab.ui.control.Button
        UITable             matlab.ui.control.Table
        ReflectanciaButton  matlab.ui.control.Button
        Button              matlab.ui.control.Button
        Button_2            matlab.ui.control.Button
        RightPanel          matlab.ui.container.Panel
        GridLayout3         matlab.ui.container.GridLayout
        UIAxes              matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        pathname;
        fileNames;
        t1 = {};
        t2 = {};
        t3 = {};
        t4 = {};
        
        t1Data = zeros(2161,1);
        t2Data = zeros(2161,1);
        t3Data = zeros(2161,1);
        t4Data = zeros(2161,1);
        
        wave_new;
        t1Reflectancia;
        t2Reflectancia;
        t3Reflectancia;
        t4Reflectancia;

    end
    
    methods (Access = private)
        
        function promediarRepeticiones(app)
           for i = 1:length(app.t1Data)
               app.t1Data(i) = app.t1Data(i) / length(app.t1);
           end
           %disp(app.t1Data(:,1))
           for i = 1:length(app.t2Data)
               app.t2Data(i) = app.t2Data(i) / length(app.t2);
           end
           for i = 1:length(app.t3Data)
               app.t3Data(i) = app.t3Data(i) / length(app.t3);
           end
           for i = 1:length(app.t4Data)
               app.t4Data(i) = app.t4Data(i) / length(app.t4);
           end
            
        end
        
        function graficarReflectancias(app)
            app.t1Reflectancia = calculoReflectancia(app.wave_new, app.t1Data);
            app.t2Reflectancia = calculoReflectancia(app.wave_new, app.t2Data);
            app.t3Reflectancia = calculoReflectancia(app.wave_new, app.t3Data);
            app.t4Reflectancia = calculoReflectancia(app.wave_new, app.t4Data);
            
            indices = [350:1:2500];
            app.UIAxes.XLim = [350 2500];
            app.UIAxes.YLim = [0 0.7];
            plot(app.UIAxes,indices,app.t1Reflectancia);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes,indices,app.t2Reflectancia);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes,indices,app.t3Reflectancia);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes,indices,app.t4Reflectancia);
            hold(app.UIAxes, 'on');
            legend(app.UIAxes,{'T1','T2','T3','T4'},"Location","northwest", "FontSize",14);
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.WindowState = 'maximized';
            app.UIAxes.XLim = [350 2500];
            app.UIAxes.YLim = [0 0.7];
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            [app.fileNames,app.pathname] = uigetfile("*.sig","MultiSelect","on");  
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            app.fileNames = app.fileNames.';
            app.UITable.Data = app.fileNames;  
                        
        end

        % Button pushed function: ReflectanciaButton
        function ReflectanciaButtonPushed(app, event)
            for i = 1:length(app.fileNames)
                fileName = app.fileNames{i};
                finder = contains(fileName,'T1');
                file = fullfile(app.pathname,fileName);
                fileID = fopen(file);
                
                [wavelength, data, new_wave, new_data] = leerArchivo(fileName);
                [app.wave_new, data_new] = interpolar(wavelength, data, new_wave, new_data);
                if(finder == 1)
                    app.t1 = [app.t1,fileName];
                     for j = 1:length(data_new)
                        app.t1Data(j) = app.t1Data(j) + data_new(j);
                     end 
                end
                finder = contains(fileName,'T2');
                if(finder == 1)
                    app.t2 = [app.t2,fileName];
                    for j = 1:length(data_new)
                        app.t2Data(j) = app.t2Data(j) + data_new(j);
                    end    
                end
                finder = contains(fileName,'T3');
                if(finder == 1)
                    app.t3 = [app.t3,fileName];
                    for j = 1:length(data_new)
                        app.t3Data(j) = app.t3Data(j) + data_new(j);
                    end    
                end
                finder = contains(fileName,'T4');
                if(finder == 1)
                    app.t4 = [app.t4,fileName];
                    for j = 1:length(data_new)
                        app.t4Data(j) = app.t4Data(j) + data_new(j);
                    end 
                end
                fclose(fileID);
            end
            promediarRepeticiones(app);
            graficarReflectancias(app);

        end

        % Button pushed function: Button, Button_2
        function ButtonPushed(app, event)
            app2();
            app.delete;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.Button_3 = uibutton(app.GridLayout2, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'openFileIcon.png';
            app.Button_3.Text = '';
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Archivos'};
            app.UITable.RowName = {};
            app.ReflectanciaButton = uibutton(app.GridLayout2, 'push');
            app.ReflectanciaButton.ButtonPushedFcn = createCallbackFcn(app, @ReflectanciaButtonPushed, true);
            app.ReflectanciaButton.Text = 'Reflectancia';
            app.Button = uibutton(app.GridLayout2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'backButton.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.GridLayout2, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_2.Icon = 'homeButton.png';
            app.Button_2.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = readFiles

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end