classdef readFilesNxM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        Button_3                      matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        ReflectanciaButton            matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        Button_2                      matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        Label2                        matlab.ui.control.Label
        Label3                        matlab.ui.control.Label
        AcotarReflectanciaLabel       matlab.ui.control.Label
        IzquierdaEditFieldLabel       matlab.ui.control.Label
        IzquierdaEditField            matlab.ui.control.NumericEditField
        DerechaEditFieldLabel         matlab.ui.control.Label
        DerechaEditField              matlab.ui.control.NumericEditField
        AcotarButton                  matlab.ui.control.Button
        EscalasdelareflectanciaLabel  matlab.ui.control.Label
        CalcularIndicesLabel          matlab.ui.control.Label
        IndicesButton                 matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        fileNames;
        pathName;
        tratamientos;
        repeticiones;
        cantidadTratamientos;
        numRepeticiones;
        tData;        
        wave_new;
        data_new;
        reflectancias;
        idBD;
        
        reflectanciaAcotada;
    end
    
    methods (Access = private)
       
                     
        function promediarRepeticiones(app)
            
            for i = 1:app.cantidadTratamientos
                for j = 1:2151
                    app.tData(i,j) = app.tData(i,j)/app.repeticiones;
                end
            end
        end
        function [flag] = contiene(app, list , element)
            flag = 0;
            for i = 1:length(list)
                %fprintf('elemento de la lista = %s vs elemento = %s\n',list(i),element);                
                if(strcmpi(list(i),element)==1)
                    flag = 1;
                    return;
                end
            end
        end
        
        function graficarReflectancias(app)
            data = zeros(2151,1);
            indices = [350:1:2500];
            for i = 1:app.cantidadTratamientos               
                data = app.reflectancias(i,:);
                plot(app.UIAxes,indices,data);
                hold(app.UIAxes,'on');                
            end
            legend(app.UIAxes, app.tratamientos, "Location","northeast","FontSize",14);
            app.Label.FontSize = 14;
            app.Label2.FontSize = 14;
            app.Label3.FontSize = 14;
            app.Label.Text = 'VIS 400-700nm';  
            app.Label2.Text = 'NIR 700-1200nm';
            app.Label3.Text = 'SWIR 1300-2500nm';
            
            graficarLimites(app);
                                   
        end
        
        function graficarLimites(app)                        
            xline(app.UIAxes,400);
            xline(app.UIAxes,700);
            xline(app.UIAxes,1200);
            xline(app.UIAxes,1300);
            xline(app.UIAxes,2500);
        end
        
        function actualizarGrafico(app)
            hold(app.UIAxes,'off');
            limiteIzquierdo = app.IzquierdaEditField.Value;
            limiteDerecho = app.DerechaEditField.Value;
            
            if limiteIzquierdo >= limiteDerecho 
                return;
            end
            if limiteIzquierdo <= 0 || limiteDerecho <= 0
                return;
            end
            data = zeros(2151,1);
            app.reflectanciaAcotada = zeros(app.cantidadTratamientos, limiteDerecho-limiteIzquierdo);
            for i = 1:app.cantidadTratamientos
                data = app.reflectancias(i,:);
                [reflectancia,valores] = acotarReflectancia(data,limiteIzquierdo,limiteDerecho);
                for j = 1:length(reflectancia)
                    app.reflectanciaAcotada(i,j) = reflectancia(j);
                end
                app.UIAxes.XLim = [350 2500];
                app.UIAxes.YLim = [0 0.7];
                plot(app.UIAxes,valores,reflectancia);
                hold(app.UIAxes, 'on');
                fprintf('Imprime en el ciclo %d\n',i);
            end
            graficarLimites(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, tratamientos, repeticiones, idBD)
            app.UIFigure.WindowState = 'maximized';
            app.cantidadTratamientos = tratamientos;
            app.repeticiones = repeticiones;
            app.idBD = idBD;
            
            disp('bdReadfNXM')
            disp(app.idBD);
            
            app.UIAxes.XLim = [350 2500];
            app.UIAxes.YLim = [0 0.7];
            set(0,'DefaultLegendAutoUpdate','off');
            set(app.ReflectanciaButton,'Enable', 'off');
            set(app.AcotarButton,'Enable', 'off');
            set(app.IndicesButton, 'Enable', 'off');
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            %setear la carpeta de archivos a HYPER
            cd 'C':\
            mkdir HYPER
            %cd 'C:\HYPER'
            
            [app.fileNames, app.pathName] = uigetfile("*.sig","MultiSelect","on");
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
            cd ([app.pathName])
            
            contador = 0;
            app.tratamientos = [];
            app.fileNames = cellstr(app.fileNames);
            disp(length(app.fileNames))
            for i = 1:length(app.fileNames)
                fileName = char(app.fileNames(i));
                t = fileName(1:2);
                t = convertCharsToStrings(t);
                if(isempty(app.tratamientos))
                    app.tratamientos = [app.tratamientos, t];
                    contador = contador + 1;
                else
                    if(contiene(app, app.tratamientos, t) == 0)
                        app.tratamientos = [app.tratamientos, {t}];
                        contador = contador + 1;
                    end
                end
            end
            fprintf('contador = %d\n', contador);
            for i = 1:length(app.tratamientos)
                disp(app.tratamientos(i));
            end
            if(contador < app.cantidadTratamientos || contador > app.cantidadTratamientos)
                f = msgbox('Cantidad incorrecta de tratamientos','Error','error');
                set(app.ReflectanciaButton,'Enable','off');
                return;
            else
                app.fileNames = app.fileNames.';
                app.UITable.Data = app.fileNames;
                set(app.ReflectanciaButton, 'Enable', 'on');
            end

        end

        % Button pushed function: ReflectanciaButton
        function ReflectanciaButtonPushed(app, event)
           d = uiprogressdlg(app.UIFigure,'Title','Por favor espere', 'Message','Cargando Archivos');
           pause(.5)
           
           app.tData = zeros(app.cantidadTratamientos, 2151);
           app.reflectancias = zeros(length(app.fileNames),2151);
           app.numRepeticiones = zeros(app.cantidadTratamientos,1);
           
           d.Value = .33;
           d.Message = 'Cargando Archivos';
           pause(1);
           for i = 1:length(app.fileNames)
                fileName = app.fileNames{i};                
                file = fullfile(app.pathName,fileName);
                fileID = fopen(file);
                
                [wavelength, data, new_wave, new_data] = leerArchivo(fileName);
                [app.wave_new, app.data_new] = interpolar(wavelength, data, new_wave, new_data);
                localReflectancia = calculoReflectancia(app.wave_new, app.data_new);
                for j = 1:length(localReflectancia)
                    app.reflectancias(i,j) = app.reflectancias(i,j) + localReflectancia(j);
                end
                                
                for j = 1:app.cantidadTratamientos
                    fileName = char(app.fileNames(i));
                    t = fileName(1:2);
                    t = convertCharsToStrings(t);
                    finder = strcmpi(t,app.tratamientos(j));
                    if(finder == 1)
                        for k = 1:length(localReflectancia)
                            app.tData(j,k) = app.tData(j,k) + localReflectancia(k);
                        end
                        app.numRepeticiones(j) = app.numRepeticiones(j)+1;
                        %%fprintf("Cantidad de repeticiones de T%d es = %d\n",j,app.numRepeticiones(j));
                    end
                end
                fclose(fileID);
           end
                      
           disp('numRepts rF NxM');
           %disp(app.numRepeticiones(1));
           %disp(app.numRepeticiones(2));
           
           promediarRepeticiones(app); 
           d.Value = .67;
           d.Message = 'Procesando los Datos';
           pause(1);
           graficarReflectancias(app);
           d.Value = 1;
           d.Message = 'Finalizando';
           pause(1);
           set(app.AcotarButton,'Enable','on');
           set(app.IndicesButton,'Enable','on');
           close(d);
           
        end

        % Button pushed function: AcotarButton
        function AcotarButtonPushed(app, event)
            actualizarGrafico(app);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            NxM(app.idBD);
            app.delete;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app2();
            app.delete;
        end

        % Button pushed function: IndicesButton
        function IndicesButtonPushed(app, event)
            IndicesNxM(app.reflectancias, app.tratamientos, app.idBD);
            app.delete;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {817, 817};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {190, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.Button_3 = uibutton(app.GridLayout2, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'openFileIcon.png';
            app.Button_3.Text = '';
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Archivos'};
            app.UITable.RowName = {};
            app.ReflectanciaButton = uibutton(app.GridLayout2, 'push');
            app.ReflectanciaButton.ButtonPushedFcn = createCallbackFcn(app, @ReflectanciaButtonPushed, true);
            app.ReflectanciaButton.Text = 'Reflectancia';
            app.Button = uibutton(app.GridLayout2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'backButton.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.GridLayout2, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'homeButton.png';
            app.Button_2.Text = '';
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = '';
            app.Label2 = uilabel(app.GridLayout2);
            app.Label2.Text = '';
            app.Label3 = uilabel(app.GridLayout2);
            app.Label3.Text = '';
            app.AcotarReflectanciaLabel = uilabel(app.GridLayout2);
            app.AcotarReflectanciaLabel.Text = 'Acotar Reflectancia';
            app.IzquierdaEditFieldLabel = uilabel(app.GridLayout2);
            app.IzquierdaEditFieldLabel.Text = 'Izquierda ';
            app.IzquierdaEditField = uieditfield(app.GridLayout2, 'numeric');
            app.IzquierdaEditField.Value = 350;
            app.DerechaEditFieldLabel = uilabel(app.GridLayout2);
            app.DerechaEditFieldLabel.Text = 'Derecha ';
            app.DerechaEditField = uieditfield(app.GridLayout2, 'numeric');
            app.DerechaEditField.Value = 2500;
            app.AcotarButton = uibutton(app.GridLayout2, 'push');
            app.AcotarButton.ButtonPushedFcn = createCallbackFcn(app, @AcotarButtonPushed, true);
            app.AcotarButton.Text = 'Acotar';
            app.EscalasdelareflectanciaLabel = uilabel(app.GridLayout2);
            app.EscalasdelareflectanciaLabel.Text = 'Escalas de la reflectancia';
            app.CalcularIndicesLabel = uilabel(app.GridLayout2);
            app.CalcularIndicesLabel.Text = 'Calcular Indices';
            app.IndicesButton = uibutton(app.GridLayout2, 'push');
            app.IndicesButton.ButtonPushedFcn = createCallbackFcn(app, @IndicesButtonPushed, true);
            app.IndicesButton.Text = 'Indices';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = readFilesNxM(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end