classdef testAndes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        GridLayout2              matlab.ui.container.GridLayout
        Button_3                 matlab.ui.control.Button
        EditField                matlab.ui.control.EditField
        ReflectanciaButton       matlab.ui.control.Button
        AcotarButton             matlab.ui.control.Button
        IndicesButton            matlab.ui.control.Button
        Button                   matlab.ui.control.Button
        IzquierdaEditFieldLabel  matlab.ui.control.Label
        DerechaEditFieldLabel    matlab.ui.control.Label
        Button_2                 matlab.ui.control.Button
        IzquierdaEditField       matlab.ui.control.NumericEditField
        DerechaEditField         matlab.ui.control.NumericEditField
        RightPanel               matlab.ui.container.Panel
        GridLayout3              matlab.ui.container.GridLayout
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        filename;
        pathname;
        reflectancia;
        indices;
    end
    
    methods (Access = private)
        function updatePlot(app)
            limiteIzquierdo = app.IzquierdaEditField.Value;
            limiteDerecho = app.DerechaEditField.Value;
            
            if limiteIzquierdo >= limiteDerecho 
                return;
            end
            if limiteIzquierdo <= 0 | limiteDerecho <= 0
                return;
            end
            
            [new_reflectancia,valores] = acotarReflectancia(app.reflectancia,limiteIzquierdo,limiteDerecho);
            
            %nueva reflectancia
            app.reflectancia = new_reflectancia;
            app.UIAxes.XLim = [350 2500];
            app.UIAxes.YLim = [0 0.7];
            plot(app.UIAxes,valores,new_reflectancia);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.WindowState = 'maximized';
            app.UIAxes.XLim = [350 2500];
            app.UIAxes.YLim = [0 0.7];
        end

        % Button pushed function: Button_3
        function FileButtonPushed(app, event)
            [app.filename, app.pathname] = uigetfile({'*.sig'}, 'Seleccionar Archivo...');
            if ~ischar(app.filename)
                return; %usuario cancelo la operacion
            end
            file = fullfile(app.pathname,app.filename);
            [fid, msg] = fopen(file,'r');
            if fid == -1
                error(msg);
            end
            figure(app.UIFigure)
            app.EditField.Value = app.filename;
            app.EditField.Editable = false;
        end

        % Button pushed function: ReflectanciaButton
        function ReflectanciaButtonPushed(app, event)
            
            [wavelength, data, new_wave, new_data] = leerArchivo(app.filename);
            [wave_new, data_new] = interpolar(wavelength, data, new_wave, new_data);
            app.reflectancia = calculoReflectancia(wave_new, data_new);
            
            app.indices = [350:1:2500];
            app.UIAxes.XLim = [350 2500];
            app.UIAxes.YLim = [0 0.7];
            plot(app.UIAxes,app.indices,app.reflectancia);

        end

        % Button pushed function: AcotarButton
        function AcotarButtonPushed(app, event)
            updatePlot(app);
        end

        % Button pushed function: IndicesButton
        function IndicesButtonPushed(app, event)
            app1(app.reflectancia, app.filename);
            app.delete
        end

        % Button pushed function: Button, Button_2
        function ButtonPushed(app, event)
            app2();
            app.delete;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.Button_3 = uibutton(app.GridLayout2, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @FileButtonPushed, true);
            app.Button_3.Icon = 'openFileIcon.png';
            app.Button_3.Text = '';
            app.EditField = uieditfield(app.GridLayout2, 'text');
            app.ReflectanciaButton = uibutton(app.GridLayout2, 'push');
            app.ReflectanciaButton.ButtonPushedFcn = createCallbackFcn(app, @ReflectanciaButtonPushed, true);
            app.ReflectanciaButton.Text = 'Reflectancia';
            app.AcotarButton = uibutton(app.GridLayout2, 'push');
            app.AcotarButton.ButtonPushedFcn = createCallbackFcn(app, @AcotarButtonPushed, true);
            app.AcotarButton.Text = 'Acotar';
            app.IndicesButton = uibutton(app.GridLayout2, 'push');
            app.IndicesButton.ButtonPushedFcn = createCallbackFcn(app, @IndicesButtonPushed, true);
            app.IndicesButton.Text = 'Indices';
            app.Button = uibutton(app.GridLayout2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'backButton.png';
            app.Button.Text = '';
            app.IzquierdaEditFieldLabel = uilabel(app.GridLayout2);
            app.IzquierdaEditFieldLabel.Text = 'Izquierda';
            app.DerechaEditFieldLabel = uilabel(app.GridLayout2);
            app.DerechaEditFieldLabel.Text = 'Derecha';
            app.Button_2 = uibutton(app.GridLayout2, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_2.Icon = 'homeButton.png';
            app.Button_2.Text = '';
            app.IzquierdaEditField = uieditfield(app.GridLayout2, 'numeric');
            app.DerechaEditField = uieditfield(app.GridLayout2, 'numeric');
            app.DerechaEditField.Value = 2500;
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testAndes

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end